classdef designImageRetrieval < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        QualearrayButtonGroup          matlab.ui.container.ButtonGroup
        SelfAttVecButton               matlab.ui.control.RadioButton
        SimilarityImageButton          matlab.ui.control.RadioButton
        popupmenu_NumOfReturnedImages  matlab.ui.control.DropDown
        CercaButton                    matlab.ui.control.Button
        ImmaginecaricataPanel          matlab.ui.container.Panel
        ImmaginiDatabasePanel          matlab.ui.container.Panel
        Image_52                       matlab.ui.control.Image
        Image_51                       matlab.ui.control.Image
        Image_50                       matlab.ui.control.Image
        Image_49                       matlab.ui.control.Image
        Image_48                       matlab.ui.control.Image
        Image_47                       matlab.ui.control.Image
        Image_46                       matlab.ui.control.Image
        Image_45                       matlab.ui.control.Image
        Image_44                       matlab.ui.control.Image
        Image_43                       matlab.ui.control.Image
        Image_42                       matlab.ui.control.Image
        Image_41                       matlab.ui.control.Image
        Image_40                       matlab.ui.control.Image
        Image_39                       matlab.ui.control.Image
        Image_38                       matlab.ui.control.Image
        Image_37                       matlab.ui.control.Image
        Image_36                       matlab.ui.control.Image
        Image_35                       matlab.ui.control.Image
        Image_34                       matlab.ui.control.Image
        Image_33                       matlab.ui.control.Image
        Image_32                       matlab.ui.control.Image
        Image_31                       matlab.ui.control.Image
        Image_30                       matlab.ui.control.Image
        Image_29                       matlab.ui.control.Image
        Image_28                       matlab.ui.control.Image
        Image_27                       matlab.ui.control.Image
        Image_26                       matlab.ui.control.Image
        Image_25                       matlab.ui.control.Image
        Image_24                       matlab.ui.control.Image
        Image_23                       matlab.ui.control.Image
        Image_22                       matlab.ui.control.Image
        Image_21                       matlab.ui.control.Image
        Image_20                       matlab.ui.control.Image
        Image_19                       matlab.ui.control.Image
        Image_18                       matlab.ui.control.Image
        Image_17                       matlab.ui.control.Image
        Image_16                       matlab.ui.control.Image
        Image_15                       matlab.ui.control.Image
        Image_14                       matlab.ui.control.Image
        Image_13                       matlab.ui.control.Image
        Image_12                       matlab.ui.control.Image
        Image_11                       matlab.ui.control.Image
        Image_10                       matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        btn_ricercaImmagini            matlab.ui.control.Button
        ImmaginiSimiliPanel            matlab.ui.container.Panel
        MarianiFilippoRoselliPaoloPetrosilliMarcoLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
    end

    
    properties (Access = private)

    end
    
    properties (Access = public)
        FullPathName          % the full path of the loaded image
        Vpiu                  %
        V                     %
        SimilarityImagesIndex %
    end


    methods (Access = private)
        
        
        %function results = func(app)
            
        %end
    end
    
    methods (Access = public)

        function mainFunctionSelfAtt(app, Vpiu, V)
            
            imageSize = StoreData.GetImageSize();
            directory = 'Images/foto_icon/';
            imagefiles = getImages(directory);     
            totImage = length(imagefiles);
                    
            simBest = zeros(totImage, 1);

            for index=1:totImage

                currentfilename = imagefiles(index).name;
                imdata = imread(strcat(directory, currentfilename));
                imdata = imresize(imdata, [imageSize imageSize]);
                imdata = im2gray(imdata);
                q = clm(imdata);
                
                nVpiu = size(Vpiu);
                nVpiu = nVpiu(1);
                selfAttVec = zeros(nVpiu,1);

                for ii=1:nVpiu
                    selfAttVec(ii) = selfAtt(Vpiu(ii, :), V(:, ii), q, 50);
                end

                selfAttNorm = normalization(selfAttVec(2), selfAttVec);
                simBest(index, 1) = abs(selfAttNorm);

            end

            app.SimilarityImagesIndex = sortVector(simBest, StoreData.GetMaximumImages());              

            displayImages(app, imagefiles, Vpiu, V, directory);


        end
        
        function    mainFunctionOrder(app, Vpiu, V)
            imageSize = StoreData.GetImageSize();
            directory = 'Images/foto_icon/';
            imagefiles = getImages(directory);     
            totImage = length(imagefiles);
            
            simBest = zeros(totImage, 1);
            
            for index=1:totImage
            
                currentfilename = imagefiles(index).name;
                imdata = imread(strcat(directory, currentfilename));
                imdata = imresize(imdata, [imageSize imageSize]);
                imdata = im2gray(imdata);
                q = clm(imdata);
                
                nVpiu = size(Vpiu);
                nVpiu = nVpiu(1);
                orderedVectors = zeros(nVpiu, imageSize.^2);
                
                for ii=1:nVpiu
                    X = Vpiu(ii, :); %matrix to row vector
                    orderedVectors(ii, :) = orderVector(X, q, 50);   %delta 5 sembra al limite per attendibilità
                end
                
                similarityVec = zeros(nVpiu,1);           
                
                for ii=1:nVpiu                  
                    similarityVec(ii) = sumVector(orderedVectors(ii, :));
                end
                
                simNorm = normalization(similarityVec(2), similarityVec); %1 corrisponde alla 1 foto per la ricerca, in questo caso il cerchio.
                simBest(index, 1) = abs(simNorm);
            end

            app.SimilarityImagesIndex = sortVector(simBest, StoreData.GetMaximumImages());              

            displayImages(app, imagefiles, Vpiu, V, directory);
                
        end

        function displayImages(app, imagefiles, Vpiu, V, directory)
            imageSize = StoreData.GetImageSize();
            app.ImmaginiSimiliPanel.AutoResizeChildren = 'off';
            h = [];
            h(1) = subplot(2,3,1,'Parent', app.ImmaginiSimiliPanel);
            h(2) = subplot(2,3,2,'Parent', app.ImmaginiSimiliPanel);
            h(3) = subplot(2,3,3,'Parent', app.ImmaginiSimiliPanel);
            h(4) = subplot(2,3,4,'Parent', app.ImmaginiSimiliPanel);
            h(5) = subplot(2,3,5,'Parent', app.ImmaginiSimiliPanel);

            for jj=1:StoreData.GetMaximumImages()
                
               currentfilename = imagefiles(app.SimilarityImagesIndex(jj)).name;

                imdata = imread(strcat(directory, currentfilename));
                im = image(imdata,'Parent',h(jj));
                if(jj == 1 && app.SimilarityImageButton.Value)
                    lenghtV = length(V);
                    matrixApp = zeros(lenghtV, 2);
                    imdata = imresize(imdata, [imageSize imageSize]);
                    imdata = im2gray(imdata);
                    matrixApp(:,1) = V(:,1);
                    matrixApp(:,2) = pinv(clm(imdata));

                    im.ButtonDownFcn = @(src,event)ImageClicked(app, matrixApp ,event);
                end
            end
            
        end

        function ImageClicked(app, src, ~)
                VNew = feedback(src(:,1), src(:,2));
                app.V(:,1) = VNew;
                app.Vpiu = pinv(app.V);

                %app.ImmaginiSimiliPanel = uipanel('Title', 'Immagini Simili');
                mainFunctionSelfAtt(app, app.Vpiu, app.V);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btn_ricercaImmagini
        function btn_ricercaImmaginiButtonPushed(app, event)
                app.ImmaginecaricataPanel.AutoResizeChildren = 'off';
                containerImage = [];
                containerImage(1) = subplot(1,1,1,'Parent', app.ImmaginecaricataPanel);
                [query_fname, query_pathname] = uigetfile('*.jpg', 'Selezione l imamagine che vuoi ricercare');    
                app.FullPathName = strcat(query_pathname, query_fname);  
                [pathstr, name, ext] = fileparts(app.FullPathName); 
                queryImage = imread( fullfile( pathstr, strcat(name, ext)));
                image(queryImage,'Parent',containerImage(1));

        end

        % Button pushed function: CercaButton
        function btn_ricercaImmaginiSimili(app, event)
                app.Vpiu = extractVpiuVector(app.FullPathName);
                app.V = pinv(app.Vpiu);
                
                if (app.SimilarityImageButton.Value)                
                    mainFunctionSelfAtt(app, app.Vpiu, app.V);
                else
                    mainFunctionOrder(app, app.Vpiu, app.V);
                end

        end

        % Value changed function: popupmenu_NumOfReturnedImages
        function popupmenu_NumOfReturnedImages_Callback(app, event)

            
        end

        % Callback function
        function QualearrayButtonGroupSelectionChanged(app, event)
            selectedButton = app.QualearrayButtonGroup.SelectedObject;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'On relevance feedback and similarity measure for image retrieval with synergetic neural nets';
            app.MarianiFilippoRoselliPaoloPetrosilliMarcoLabel = uilabel(app.UIFigure);
            app.MarianiFilippoRoselliPaoloPetrosilliMarcoLabel.Text = {'Mariani Filippo'; 'Roselli Paolo'; 'Petrosilli Marco'};
            app.ImmaginiSimiliPanel = uipanel(app.UIFigure);
            app.ImmaginiSimiliPanel.Title = 'Immagini Simili';
            app.btn_ricercaImmagini = uibutton(app.UIFigure, 'push');
            app.btn_ricercaImmagini.ButtonPushedFcn = createCallbackFcn(app, @btn_ricercaImmaginiButtonPushed, true);
            app.btn_ricercaImmagini.Tag = 'btn_BrowseImage';
            app.btn_ricercaImmagini.Text = 'Carica un immagine';
            app.ImmaginiDatabasePanel = uipanel(app.UIFigure);
            app.ImmaginiDatabasePanel.Title = 'Immagini Database';
            app.Image = uiimage(app.ImmaginiDatabasePanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'foto_icon', 'Screenshot 2022-12-16 alle 16.40.37.jpg');
            app.Image_10 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_11 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_12 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_13 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_13.ImageSource = fullfile(pathToMLAPP, 'Images', 'foto_icon', 'Screenshot 2022-12-16 alle 16.41.00.jpg');
            app.Image_14 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_15 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_16 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_17 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_17.ImageSource = fullfile(pathToMLAPP, 'Images', 'foto_icon', 'Screenshot 2022-12-16 alle 16.41.19.jpg');
            app.Image_18 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_19 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_20 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_21 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_21.ImageSource = fullfile(pathToMLAPP, 'Images', 'foto_icon', 'Screenshot 2022-12-16 alle 16.41.36.jpg');
            app.Image_22 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_23 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_24 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_25 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_25.ImageSource = fullfile(pathToMLAPP, 'Images', 'foto_icon', 'Screenshot 2022-12-16 alle 16.41.57.jpg');
            app.Image_26 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_27 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_28 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_29 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_29.ImageSource = fullfile(pathToMLAPP, 'Images', 'foto_icon', 'Screenshot 2022-12-16 alle 16.42.31.jpg');
            app.Image_30 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_31 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_32 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_33 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_34 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_35 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_36 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_37 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_38 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_39 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_40 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_41 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_42 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_43 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_44 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_45 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_46 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_47 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_48 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_49 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_50 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_51 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_52 = uiimage(app.ImmaginiDatabasePanel);
            app.ImmaginecaricataPanel = uipanel(app.UIFigure);
            app.ImmaginecaricataPanel.Title = 'Immagine caricata';
            app.CercaButton = uibutton(app.UIFigure, 'push');
            app.CercaButton.ButtonPushedFcn = createCallbackFcn(app, @btn_ricercaImmaginiSimili, true);
            app.CercaButton.Text = 'Cerca';
            app.popupmenu_NumOfReturnedImages = uidropdown(app.UIFigure);
            app.popupmenu_NumOfReturnedImages.Items = {'5', '10', '15'};
            app.popupmenu_NumOfReturnedImages.ValueChangedFcn = createCallbackFcn(app, @popupmenu_NumOfReturnedImages_Callback, true);
            app.popupmenu_NumOfReturnedImages.Tag = 'popupmenu_NumOfReturnedImages';
            app.popupmenu_NumOfReturnedImages.Value = '5';
            app.QualearrayButtonGroup = uibuttongroup(app.UIFigure);
            app.QualearrayButtonGroup.Title = 'Quale array?';
            app.SimilarityImageButton = uiradiobutton(app.QualearrayButtonGroup);
            app.SimilarityImageButton.Tag = 'SimilarityImageButton';
            app.SimilarityImageButton.Text = 'Similarity Image';
            app.SimilarityImageButton.Value = true;
            app.SelfAttVecButton = uiradiobutton(app.QualearrayButtonGroup);
            app.SelfAttVecButton.Tag = 'SelfAttVecButton';
            app.SelfAttVecButton.Text = 'Self Att Vec';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = designImageRetrieval

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end